classdef appSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GateStaticCoefficientofFrictionSliderLabel  matlab.ui.control.Label
        GateStaticCoefficientofFrictionSlider  matlab.ui.control.Slider
        WaterDepthmSliderLabel  matlab.ui.control.Label
        WaterDepthmSlider       matlab.ui.control.Slider
        DropDown                matlab.ui.control.DropDown
        UIAxes                  matlab.ui.control.UIAxes
        StartResetButton        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUp(app)
      
        end

        % Callback function: DropDown, 
        % GateStaticCoefficientofFrictionSlider, 
        % GateStaticCoefficientofFrictionSlider, WaterDepthmSlider, 
        % WaterDepthmSlider
        function StaticSliderValueChanged(app, event)
               
%% 1 Initial Conditions - Design Parameters %%
%% 1.1 - General 
% Bar = 0.1 MPa

h_w = app.WaterDepthmSlider.Value;                     % Water depth [m]
pho_sw = 1030;                  % Sea water density [kg/m^3]
g = 9.81;                       % Gravity [m/s^2]

P_sw = (pho_sw * g * h_w);            % Hydrostatic pressure at 3000m [bar]

%% 1.2 - Actuator 
D_ms = 57*(10^(-3));                % Main Stem seal diameter (nom) [mm]
mu_s = 0.06;                            % Coefficient of friction across pressurised seals [-]
L_s1 = 12*(10^(-3));                  % Estimated length of critical barrier stem seal that is pressurised [mm]
L_s2 = 8*(10^(-3));                    % Estimated length of actuation chamber seal that is pressurised [mm]

%% 1.3 - Valve 
P_w = 689e+5;                      % Valve rated working pressure [bar]
D_s = 202*(10^(-3));                      % Valve seat outer sealing diameter (nom)(at gate/seat interface) [mm]
d_s = 168.27*(10^(-3));                   % Valve seat inner sealing diameter (nom) (at gate/seat interface) [mm]

mu_dyn = 0.2;                   % Gate dynamic coefficicent of friction - design [-]
mu_stat = app.GateStaticCoefficientofFrictionSlider.Value;                  % Gate static coefficient of friction - designs [-]
L_stroke = 188*(10^(-3));               % Stroke length [mm]
L_co = 19.9*(10^(-3));                   % Stroke Crack Open [mm]
L_start = 1*(10^(-3));                    % Stroke Start move [mm]

%% 2 Analysis - 2.1 Resultant forces on Actuating Mechanism %% 
%% 2.1.1 - Gate Frictional Resistance 
F_gfstat = (pi/4)*(D_s^2)*P_w*mu_stat;                % Gate friction force - static: (against seat at full differential) [kN]
F_gfdyn = (pi/4)*(D_s^2)*P_w*mu_dyn;                  % Gate friction force - dynamic: (against seat at full differential) [kN]

%% 2.1.2 - Seal Frictional Resistance 
F_sfd = (mu_s*P_w*L_s1*pi*D_ms) + (mu_s*P_sw*L_s2*pi*D_ms);              % Hydraulic seal frictional resistance: at depth [kN]
F_sfs = mu_s*P_w*L_s1*pi*D_ms;                                          % Hydraulic seal fritional resistance: at surface [kN]

%% 2.1.3 - Stem Pressure End load
F_ms = (pi/4)*(D_ms^2)*P_w;                     % Main stem end load due to bore pressure [kN]
F_msc = (pi/4)*(D_ms^2)*P_sw;                    % Main stem end load due to seawater [kN]

%% 2.1.5 - Summation of Forces at Depth with bore pressure
%% Extend actuator (open valve)
F_rde = F_gfstat + F_sfd + F_ms - F_msc;                % Required actuating force to crack open [kN]
F_rde_running = F_sfd + F_ms - F_msc;                   % Required actuating force to running open [kN]
%% Retract Actuator (close valve)
F_rdr = F_gfstat + F_sfd + F_msc - F_ms;                % Required actuating force to pinch close [kN]
F_rdr_running = F_sfd + F_msc - F_ms;                   % Required actuaing force to running close [kN]            

%% 2.2 Operational Assessment of Actuating Mechanism %%
%% 2.2.1 - Roller Screw Torque
P = 15*(10^(-3));                     % Lead roller screw [mm]
d = 87*(10^(-3));                     % Diameter rollerscrew [mm]

alpha_ha = atand(P/(pi*d));         % Helix angle [°]

mu_prac = 0.012;                     % Break-away torque could be up to twice the friction as the practical fraction [-]
mu_start = 0.024;                       
mu_theoretical = 0.009;

eta = 1/(1 + ((pi*d)/P)*mu_prac);               % Efficiency roller screw, rolling [%]
eta_start = 1/(1 + ((pi*d)/P)*mu_start);        % Efficiency roller screw, start [%]
eta_theoretical = (1/(1+((pi*d)/P)*mu_theoretical));        % Effienciency roller screw, theoretical [%]
eta_in_theoretical = 2 - (1/eta_theoretical);    % Indirect efficiency roller screw, theoretical [%]

% T = (F*P)/(2*pi);                               % Roller screw torque driving [Nm]
% T = (F*P*eta)/(2*pi);                           % Roller screw torque retaining/driven [Nm]

%% 2.2.2 - Bearing Torque
mu_b = 0.004;                               % Bearing friction (Roller Bearing SKF 7232) [-]
mu_b_bc = 0.002;                            % Bearing friction (best case) [-]
mu_bstart = mu_b*1.5;                       % Bearing friction start [-]
D_b = 290*(10^(-3));                              % Bearing Outer Diameter [mm]
d_b = 160*(10^(-3));                              % Bearing Inner Diameter [mm]
d_m = (D_b+d_b)/2;                          % Pitch Diameter Bearing [mm]

%% 2.2.5 - Required roller screw torque, depth
T_d_start = ((F_rde*P)/(2*pi*eta_start)) + ((F_rde*mu_bstart*d_m)/2);                % Start to move, depth [N*m]
T_d_co = ((F_rde*P)/(2*pi*eta)) + ((F_rde*mu_b*d_m)/2);                             % Crack open, depth [N*m]
T_d_ro = ((F_rde_running*P)/(2*pi*eta)) + ((F_rde_running*mu_b*d_m)/2);             % Running open, depth  [N*m]
T_d_rc = ((F_rdr_running*P*eta)/(2*pi)) - ((F_rdr_running*mu_b*d_m)/2);             % Running close, depth  [N*m]

if (mu_stat > 0)
    T_d_pc = ((F_rdr*P)/(2*pi*eta)) + ((F_rdr*mu_b*d_m)/2);                             % Pinch Close, depth [N*m]       % Running close, depth  [N*m]
else 
    T_d_pc = T_d_rc;
end 


%% 2.3 - Operational Assessment of Gearbox %%
%% 2.3.1 - Gearbox
GB_stages = 3;                  % Number of gearbox stages [-]
eta_gb = 0.95;                  % Gearbox efficiency [-]
N_high = 230;                   % Gear ratios, high [-]                     

%% 2.3.4 - Required motor torque, depth
T_md_start = (T_d_start/(N_high*(eta_gb^GB_stages)));            % Start to move, depth [N*m]
T_md_co = (T_d_co/(N_high*(eta_gb^GB_stages)));                  % Crack open, depth [N*m]
T_md_ro = (T_d_ro/(N_high*(eta_gb^GB_stages)));                  % Running open, depth [N*m]
T_md_rc = (T_d_rc/(N_high*(eta_gb^(GB_stages))));               % Running close, depth [N*m]
T_md_pc = (T_d_pc/(N_high*(eta_gb^GB_stages)));                  % Pinch close, depth [N*m]


%% 2.3.6 - Required motor mechanical energy, depth
E_md_start = (L_start/P)*N_high*(2*pi)*T_md_start;                                          % Start to move, depth [W*hr, kJ]
E_md_co = ((L_co-L_start)/P)*(N_high/(eta_gb^GB_stages))*(2*pi)*T_md_co;                    % Crack open, surface [W*hr, kJ]
E_md_ro = ((L_stroke-L_co-L_start)/P)*(N_high/eta_gb^GB_stages)*(2*pi)*T_md_ro;             % Running Open, depth [W*hr, kJ]
E_md_rc = ((L_stroke-L_co)/P)*N_high*(eta_gb^(GB_stages))*(2*pi)*T_md_rc;                  % Running Close, depth [W*hr, kJ]
E_md_pc = (L_co/P)*(N_high/(eta_gb^GB_stages))*(2*pi)*T_md_pc;                              % Pinch Close, depth [W*hr, kJ]

            
%% Extra Initials
L_stroke2 = L_stroke + (L_stroke-L_co);      % Close running stroke [mm]
L_co2 = L_stroke*2;                     % From close running stroke to pinch close [mm]
L_extra = 0.000000001;                                 % For å sette grafene opp til å være 
                                                    % konstant over den lengde de har [mm]     
%% Graphs %%
%% Manuelt setup fra Kalkulasjonsrapport
x_force = [0, L_co, L_co, L_stroke, L_stroke, L_stroke2, L_stroke2, L_co2];
x_motor = [0, L_start,(L_start+L_extra), L_co, (L_co+L_extra), L_stroke, (L_stroke+L_extra), L_stroke2, (L_stroke2+L_extra), L_co2];
y_roller = [0, T_d_start,T_d_co, T_d_co, T_d_ro, T_d_ro, T_d_rc, T_d_rc, T_d_pc, T_d_pc];
y_force = [F_rde, F_rde, F_rde_running, F_rde_running, F_rdr_running, F_rdr_running, F_rdr, F_rdr];
y_motor = [0, T_md_start,T_md_co, T_md_co, T_md_ro, T_md_ro, T_md_rc, T_md_rc, T_md_pc, T_md_pc];
y_energy = [0, E_md_start,E_md_co, E_md_co, E_md_ro, E_md_ro, E_md_rc, E_md_rc, E_md_pc, E_md_pc];

            if strcmp(app.DropDown.Value,'Valve Forces')
                plot(app.UIAxes,x_force,y_force)
                app.UIAxes.XLim = [0 400e-3];
                app.UIAxes.YLim = [-200e3 700e3]
                app.UIAxes.XTick = [0 19.9e-3 50e-3 100e-3 150e-3 188e-3 226e-3 276e-3 323e-3 356.1e-3 376e-3]
                app.UIAxes.XTickLabel = {'0' '19.9' '50' '100' '150' '188' '150' '100' '50' '19.9' '0'}
                app.UIAxes.YTick = [-200e3 -150e3 -100e3 -50e3 0 50e3 100e3 150e3 200e3 250e3 300e3 350e3 400e3 450e3 500e3 550e3 600e3 650e3 700e3];
                app.UIAxes.YTickLabel = {'-200' '-150' '-100' '-50' '0' '50' '100' '150' '200' '250' '300' '350' '400' '450' '500' '550' '600' '650' '700'};
                app.UIAxes.Title.String = 'Valve Forces'
                app.UIAxes.Title.FontSize = 25;
                app.UIAxes.TitleFontWeight = 'bold'
                app.UIAxes.XLabel.String = 'Stroke [mm]'
                app.UIAxes.XLabel.FontSize = 15;
                app.UIAxes.YLabel.String = 'Force [kN]'
                app.UIAxes.YLabel.FontSize = 15;
                app.UIAxes.XGrid = 'on'
                app.UIAxes.YGrid = 'on'
            elseif strcmp(app.DropDown.Value,'Roller Screw Torque')
                plot(app.UIAxes, x_motor, y_roller)
                app.UIAxes.XLim = [0 400e-3];
                app.UIAxes.YLim = [-500 3000]
                app.UIAxes.XTick = [0 19.9e-3 50e-3 100e-3 150e-3 188e-3 226e-3 276e-3 323e-3 356.1e-3 376e-3]
                app.UIAxes.XTickLabel = {'0' '19.9' '50' '100' '150' '188' '150' '100' '50' '19.9' '0'}
                app.UIAxes.YTick = [-500 -250 0 250 500 750 1000 1250 1500 1750 2000 2250 2500 2750 3000]
                app.UIAxes.YTickLabel = {'-500' '-250' '0' '250' '500' '750' '1000' '1250' '1500' '1750' '2000' '2250' '2500' '2750' '3000'}
                app.UIAxes.Title.String = 'Required Roller Screw Torque'
                app.UIAxes.Title.FontSize = 25;
                app.UIAxes.TitleFontWeight = 'bold'
                app.UIAxes.XLabel.String = 'Stroke [mm]'
                app.UIAxes.XLabel.FontSize = 15;
                app.UIAxes.YLabel.String = 'Torque [Nm]'
                app.UIAxes.YLabel.FontSize = 15;
                app.UIAxes.XGrid = 'on'
                app.UIAxes.YGrid = 'on'
            elseif strcmp(app.DropDown.Value,'Motor Torque')
                plot(app.UIAxes, x_motor, y_motor)
                app.UIAxes.XLim = [0 400e-3];
                app.UIAxes.YLim = [-20 30]
                app.UIAxes.XTick = [0 19.9e-3 50e-3 100e-3 150e-3 188e-3 226e-3 276e-3 323e-3 356.1e-3 376e-3]
                app.UIAxes.XTickLabel = {'0' '19.9' '50' '100' '150' '188' '150' '100' '50' '19.9' '0'}
                app.UIAxes.YTick = [-20 -15 -10 -5 0 5 7.8 10 11.7 15 20 25 30]
                app.UIAxes.YTickLabel = {'-20' '-15' '-10' '-5' '0' '5' '7.8' '10' '11.7' '15' '20' '25' '30'}
                app.UIAxes.Title.String = 'Required Motor Torque'
                app.UIAxes.Title.FontSize = 25;
                app.UIAxes.TitleFontWeight = 'bold'
                app.UIAxes.XLabel.String = 'Stroke [mm]'
                app.UIAxes.XLabel.FontSize = 15;
                app.UIAxes.YLabel.String = 'Torque [Nm]'
                app.UIAxes.YLabel.FontSize = 15;
                app.UIAxes.XGrid = 'on'
                app.UIAxes.YGrid = 'on'
                x1=0;
                x2=400e-3;
                y1=11.7;
                y2=7.8;
                hold(app.UIAxes,'on');
                plot(app.UIAxes, [x1, x2], [y1, y1], 'r-.')
                plot(app.UIAxes, [x1, x2], [y2, y2], 'g-.')
                hold(app.UIAxes,'off');
                text(app.UIAxes,188e-3,12.7,'Maximum Motor Torque','FontSize',15)
                text(app.UIAxes,188e-3,8.8,'Nominal Motor Torque','FontSize',15)
            else strcmp(app.DropDown.Value,'Mechanical Energy')
                plot(app.UIAxes, x_motor, y_energy)
                app.UIAxes.XLim = [0 400e-3];
                app.UIAxes.YLim = [-30e3 70e3]
                app.UIAxes.XTick = [0 19.9e-3 50e-3 100e-3 150e-3 188e-3 226e-3 276e-3 323e-3 356.1e-3 376e-3]
                app.UIAxes.XTickLabel = {'0' '19.9' '50' '100' '150' '188' '150' '100' '50' '19.9' '0'}
                app.UIAxes.YTick = [-30e3 -20e3 -10e3 0 10e3 20e3 30e3 40e3 50e3 60e3 70e3]
                app.UIAxes.YTickLabel = {'-30' '-20' '-10' '0' '10' '20' '30' '40' '50' '60' '70'}
                app.UIAxes.Title.String = 'Required Mechanical Energy'
                app.UIAxes.Title.FontSize = 25;
                app.UIAxes.TitleFontWeight = 'bold'
                app.UIAxes.XLabel.String = 'Stroke [mm]'
                app.UIAxes.XLabel.FontSize = 15;
                app.UIAxes.YLabel.String = 'Mechanical Energy [kJ]'
                app.UIAxes.YLabel.FontSize = 15; 
                app.UIAxes.XGrid = 'on'
                app.UIAxes.YGrid = 'on'
             end
      
        end

        % Button pushed function: StartResetButton
        function StartResetButtonPushed(app, event)
            
            app.WaterDepthmSlider.Value = 3050;
            app.GateStaticCoefficientofFrictionSlider.Value = 0.2;
            
            %% 1 Initial Conditions - Design Parameters %%
%% 1.1 - General 
% Bar = 0.1 MPa

h_w = app.WaterDepthmSlider.Value;                     % Water depth [m]
pho_sw = 1030;                  % Sea water density [kg/m^3]
g = 9.81;                       % Gravity [m/s^2]

P_sw = (pho_sw * g * h_w);            % Hydrostatic pressure at 3000m [bar]

%% 1.2 - Actuator 
D_ms = 57*(10^(-3));                % Main Stem seal diameter (nom) [mm]
mu_s = 0.06;                            % Coefficient of friction across pressurised seals [-]
L_s1 = 12*(10^(-3));                  % Estimated length of critical barrier stem seal that is pressurised [mm]
L_s2 = 8*(10^(-3));                    % Estimated length of actuation chamber seal that is pressurised [mm]

%% 1.3 - Valve 
P_w = 689e+5;                      % Valve rated working pressure [bar]
D_s = 202*(10^(-3));                      % Valve seat outer sealing diameter (nom)(at gate/seat interface) [mm]
d_s = 168.27*(10^(-3));                   % Valve seat inner sealing diameter (nom) (at gate/seat interface) [mm]

mu_dyn = 0.2;                   % Gate dynamic coefficicent of friction - design [-]
mu_stat = app.GateStaticCoefficientofFrictionSlider.Value;                  % Gate static coefficient of friction - designs [-]
L_stroke = 188*(10^(-3));               % Stroke length [mm]
L_co = 19.9*(10^(-3));                   % Stroke Crack Open [mm]
L_start = 1*(10^(-3));                    % Stroke Start move [mm]

%% 2 Analysis - 2.1 Resultant forces on Actuating Mechanism %% 
%% 2.1.1 - Gate Frictional Resistance 
F_gfstat = (pi/4)*(D_s^2)*P_w*mu_stat;                % Gate friction force - static: (against seat at full differential) [kN]
F_gfdyn = (pi/4)*(D_s^2)*P_w*mu_dyn;                  % Gate friction force - dynamic: (against seat at full differential) [kN]

%% 2.1.2 - Seal Frictional Resistance 
F_sfd = (mu_s*P_w*L_s1*pi*D_ms) + (mu_s*P_sw*L_s2*pi*D_ms);              % Hydraulic seal frictional resistance: at depth [kN]
F_sfs = mu_s*P_w*L_s1*pi*D_ms;                                          % Hydraulic seal fritional resistance: at surface [kN]

%% 2.1.3 - Stem Pressure End load
F_ms = (pi/4)*(D_ms^2)*P_w;                     % Main stem end load due to bore pressure [kN]
F_msc = (pi/4)*(D_ms^2)*P_sw;                    % Main stem end load due to seawater [kN]

%% 2.1.5 - Summation of Forces at Depth with bore pressure
%% Extend actuator (open valve)
F_rde = F_gfstat + F_sfd + F_ms - F_msc;                % Required actuating force to crack open [kN]
F_rde_running = F_sfd + F_ms - F_msc;                   % Required actuating force to running open [kN]
%% Retract Actuator (close valve)
F_rdr = F_gfstat + F_sfd + F_msc - F_ms;                % Required actuating force to pinch close [kN]
F_rdr_running = F_sfd + F_msc - F_ms;                   % Required actuaing force to running close [kN]            

%% 2.2 Operational Assessment of Actuating Mechanism %%
%% 2.2.1 - Roller Screw Torque
P = 15*(10^(-3));                     % Lead roller screw [mm]
d = 87*(10^(-3));                     % Diameter rollerscrew [mm]

alpha_ha = atand(P/(pi*d));         % Helix angle [°]

mu_prac = 0.012;                     % Break-away torque could be up to twice the friction as the practical fraction [-]
mu_start = 0.024;                       
mu_theoretical = 0.009;

eta = 1/(1 + ((pi*d)/P)*mu_prac);               % Efficiency roller screw, rolling [%]
eta_start = 1/(1 + ((pi*d)/P)*mu_start);        % Efficiency roller screw, start [%]
eta_theoretical = (1/(1+((pi*d)/P)*mu_theoretical));        % Effienciency roller screw, theoretical [%]
eta_in_theoretical = 2 - (1/eta_theoretical);    % Indirect efficiency roller screw, theoretical [%]

% T = (F*P)/(2*pi);                               % Roller screw torque driving [Nm]
% T = (F*P*eta)/(2*pi);                           % Roller screw torque retaining/driven [Nm]

%% 2.2.2 - Bearing Torque
mu_b = 0.004;                               % Bearing friction (Roller Bearing SKF 7232) [-]
mu_b_bc = 0.002;                            % Bearing friction (best case) [-]
mu_bstart = mu_b*1.5;                       % Bearing friction start [-]
D_b = 290*(10^(-3));                              % Bearing Outer Diameter [mm]
d_b = 160*(10^(-3));                              % Bearing Inner Diameter [mm]
d_m = (D_b+d_b)/2;                          % Pitch Diameter Bearing [mm]

%% 2.2.5 - Required roller screw torque, depth
T_d_start = ((F_rde*P)/(2*pi*eta_start)) + ((F_rde*mu_bstart*d_m)/2);                % Start to move, depth [N*m]
T_d_co = ((F_rde*P)/(2*pi*eta)) + ((F_rde*mu_b*d_m)/2);                             % Crack open, depth [N*m]
T_d_ro = ((F_rde_running*P)/(2*pi*eta)) + ((F_rde_running*mu_b*d_m)/2);             % Running open, depth  [N*m]
T_d_rc = ((F_rdr_running*P*eta)/(2*pi)) - ((F_rdr_running*mu_b*d_m)/2);             % Running close, depth  [N*m]

if (mu_stat > 0)
    T_d_pc = ((F_rdr*P)/(2*pi*eta)) + ((F_rdr*mu_b*d_m)/2);                             % Pinch Close, depth [N*m]       % Running close, depth  [N*m]
else 
    T_d_pc = T_d_rc;
end 


%% 2.3 - Operational Assessment of Gearbox %%
%% 2.3.1 - Gearbox
GB_stages = 3;                  % Number of gearbox stages [-]
eta_gb = 0.95;                  % Gearbox efficiency [-]
N_high = 230;                   % Gear ratios, high [-]                     

%% 2.3.4 - Required motor torque, depth
T_md_start = (T_d_start/(N_high*(eta_gb^GB_stages)));            % Start to move, depth [N*m]
T_md_co = (T_d_co/(N_high*(eta_gb^GB_stages)));                  % Crack open, depth [N*m]
T_md_ro = (T_d_ro/(N_high*(eta_gb^GB_stages)));                  % Running open, depth [N*m]
T_md_rc = (T_d_rc/(N_high*(eta_gb^(GB_stages))));               % Running close, depth [N*m]
T_md_pc = (T_d_pc/(N_high*(eta_gb^GB_stages)));                  % Pinch close, depth [N*m]


%% 2.3.6 - Required motor mechanical energy, depth
E_md_start = (L_start/P)*N_high*(2*pi)*T_md_start;                                          % Start to move, depth [W*hr, kJ]
E_md_co = ((L_co-L_start)/P)*(N_high/(eta_gb^GB_stages))*(2*pi)*T_md_co;                    % Crack open, surface [W*hr, kJ]
E_md_ro = ((L_stroke-L_co-L_start)/P)*(N_high/eta_gb^GB_stages)*(2*pi)*T_md_ro;             % Running Open, depth [W*hr, kJ]
E_md_rc = ((L_stroke-L_co)/P)*N_high*(eta_gb^(GB_stages))*(2*pi)*T_md_rc;                  % Running Close, depth [W*hr, kJ]
E_md_pc = (L_co/P)*(N_high/(eta_gb^GB_stages))*(2*pi)*T_md_pc;                              % Pinch Close, depth [W*hr, kJ]

            
%% Extra Initials
L_stroke2 = L_stroke + (L_stroke-L_co);      % Close running stroke [mm]
L_co2 = L_stroke*2;                     % From close running stroke to pinch close [mm]
L_extra = 0.000000001;                                 % For å sette grafene opp til å være 
                                                    % konstant over den lengde de har [mm]     
%% Graphs %%
%% Manuelt setup fra Kalkulasjonsrapport
x_force = [0, L_co, L_co, L_stroke, L_stroke, L_stroke2, L_stroke2, L_co2];
x_motor = [0, L_start, L_co, (L_co+L_extra), L_stroke, (L_stroke+L_extra), L_stroke2, (L_stroke2+L_extra), L_co2];
y_roller = [0, T_d_start, T_d_co, T_d_ro, T_d_ro, T_d_rc, T_d_rc, T_d_pc, T_d_pc];
y_force = [F_rde, F_rde, F_rde_running, F_rde_running, F_rdr_running, F_rdr_running, F_rdr, F_rdr];
y_motor = [0, T_md_start, T_md_co, T_md_ro, T_md_ro, T_md_rc, T_md_rc, T_md_pc, T_md_pc];
y_energy = [0, E_md_start, E_md_co, E_md_ro, E_md_ro, E_md_rc, E_md_rc, E_md_pc, E_md_pc];

            if strcmp(app.DropDown.Value,'Valve Forces')
                plot(app.UIAxes,x_force,y_force)
                app.UIAxes.XLim = [0 400e-3];
                app.UIAxes.YLim = [-200e3 700e3]
                app.UIAxes.XTick = [0 19.9e-3 50e-3 100e-3 150e-3 188e-3 226e-3 276e-3 323e-3 356.1e-3 376e-3]
                app.UIAxes.XTickLabel = {'0' '19.9' '50' '100' '150' '188' '150' '100' '50' '19.9' '0'}
                app.UIAxes.YTick = [-200e3 -150e3 -100e3 -50e3 0 50e3 100e3 150e3 200e3 250e3 300e3 350e3 400e3 450e3 500e3 550e3 600e3 650e3 700e3];
                app.UIAxes.YTickLabel = {'-200' '-150' '-100' '-50' '0' '50' '100' '150' '200' '250' '300' '350' '400' '450' '500' '550' '600' '650' '700'};
                app.UIAxes.Title.String = 'Valve Forces'
                app.UIAxes.Title.FontSize = 25;
                app.UIAxes.TitleFontWeight = 'bold'
                app.UIAxes.XLabel.String = 'Stroke [mm]'
                app.UIAxes.XLabel.FontSize = 15;
                app.UIAxes.YLabel.String = 'Force [kN]'
                app.UIAxes.YLabel.FontSize = 15;
                app.UIAxes.XGrid = 'on'
                app.UIAxes.YGrid = 'on'
            elseif strcmp(app.DropDown.Value,'Roller Screw Torque')
                plot(app.UIAxes, x_motor, y_roller)
                app.UIAxes.XLim = [0 400e-3];
                app.UIAxes.YLim = [-500 3000]
                app.UIAxes.XTick = [0 19.9e-3 50e-3 100e-3 150e-3 188e-3 226e-3 276e-3 323e-3 356.1e-3 376e-3]
                app.UIAxes.XTickLabel = {'0' '19.9' '50' '100' '150' '188' '150' '100' '50' '19.9' '0'}
                app.UIAxes.YTick = [-500 -250 0 250 500 750 1000 1250 1500 1750 2000 2250 2500 2750 3000]
                app.UIAxes.YTickLabel = {'-500' '-250' '0' '250' '500' '750' '1000' '1250' '1500' '1750' '2000' '2250' '2500' '2750' '3000'}
                app.UIAxes.Title.String = 'Required Roller Screw Torque'
                app.UIAxes.Title.FontSize = 25;
                app.UIAxes.TitleFontWeight = 'bold'
                app.UIAxes.XLabel.String = 'Stroke [mm]'
                app.UIAxes.XLabel.FontSize = 15;
                app.UIAxes.YLabel.String = 'Torque [Nm]'
                app.UIAxes.YLabel.FontSize = 15;
                app.UIAxes.XGrid = 'on'
                app.UIAxes.YGrid = 'on'
            elseif strcmp(app.DropDown.Value,'Motor Torque')
                plot(app.UIAxes, x_motor, y_motor)
                app.UIAxes.XLim = [0 400e-3];
                app.UIAxes.YLim = [-20 30]
                app.UIAxes.XTick = [0 19.9e-3 50e-3 100e-3 150e-3 188e-3 226e-3 276e-3 323e-3 356.1e-3 376e-3]
                app.UIAxes.XTickLabel = {'0' '19.9' '50' '100' '150' '188' '150' '100' '50' '19.9' '0'}
                app.UIAxes.YTick = [-20 -15 -10 -5 0 5 7.8 10 11.7 15 20 25 30]
                app.UIAxes.YTickLabel = {'-20' '-15' '-10' '-5' '0' '5' '7.8' '10' '11.7' '15' '20' '25' '30'}
                app.UIAxes.Title.String = 'Required Motor Torque'
                app.UIAxes.Title.FontSize = 25;
                app.UIAxes.TitleFontWeight = 'bold'
                app.UIAxes.XLabel.String = 'Stroke [mm]'
                app.UIAxes.XLabel.FontSize = 15;
                app.UIAxes.YLabel.String = 'Torque [Nm]'
                app.UIAxes.YLabel.FontSize = 15;
                app.UIAxes.XGrid = 'on'
                app.UIAxes.YGrid = 'on'
                x1=0;
                x2=400e-3;
                y1=11.7;
                y2=7.8;
                hold(app.UIAxes,'on');
                plot(app.UIAxes, [x1, x2], [y1, y1], 'r-.')
                plot(app.UIAxes, [x1, x2], [y2, y2], 'g-.')
                hold(app.UIAxes,'off');
                text(app.UIAxes,188e-3,12.7,'Maximum Motor Torque','FontSize',15)
                text(app.UIAxes,188e-3,8.8,'Nominal Motor Torque','FontSize',15)
            else strcmp(app.DropDown.Value,'Mechanical Energy')
                plot(app.UIAxes, x_motor, y_energy)
                app.UIAxes.XLim = [0 400e-3];
                app.UIAxes.YLim = [-30e3 70e3]
                app.UIAxes.XTick = [0 19.9e-3 50e-3 100e-3 150e-3 188e-3 226e-3 276e-3 323e-3 356.1e-3 376e-3]
                app.UIAxes.XTickLabel = {'0' '19.9' '50' '100' '150' '188' '150' '100' '50' '19.9' '0'}
                app.UIAxes.YTick = [-30e3 -20e3 -10e3 0 10e3 20e3 30e3 40e3 50e3 60e3 70e3]
                app.UIAxes.YTickLabel = {'-30' '-20' '-10' '0' '10' '20' '30' '40' '50' '60' '70'}
                app.UIAxes.Title.String = 'Required Mechanical Energy'
                app.UIAxes.Title.FontSize = 25;
                app.UIAxes.TitleFontWeight = 'bold'
                app.UIAxes.XLabel.String = 'Stroke [mm]'
                app.UIAxes.XLabel.FontSize = 15;
                app.UIAxes.YLabel.String = 'Mechanical Energy [kJ]'
                app.UIAxes.YLabel.FontSize = 15; 
                app.UIAxes.XGrid = 'on'
                app.UIAxes.YGrid = 'on'
             end
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GateStaticCoefficientofFrictionSliderLabel = uilabel(app.UIFigure);
            app.GateStaticCoefficientofFrictionSliderLabel.Text = 'Gate Static Coefficient of Friction [-]';
            app.GateStaticCoefficientofFrictionSlider = uislider(app.UIFigure);
            app.GateStaticCoefficientofFrictionSlider.Limits = [0 0.5];
            app.GateStaticCoefficientofFrictionSlider.ValueChangedFcn = createCallbackFcn(app, @StaticSliderValueChanged, true);
            app.GateStaticCoefficientofFrictionSlider.ValueChangingFcn = createCallbackFcn(app, @StaticSliderValueChanged, true);
            app.GateStaticCoefficientofFrictionSlider.Value = 0.2;
            app.WaterDepthmSliderLabel = uilabel(app.UIFigure);
            app.WaterDepthmSliderLabel.Text = 'Water Depth [m]';
            app.WaterDepthmSlider = uislider(app.UIFigure);
            app.WaterDepthmSlider.Limits = [0 6000];
            app.WaterDepthmSlider.ValueChangedFcn = createCallbackFcn(app, @StaticSliderValueChanged, true);
            app.WaterDepthmSlider.ValueChangingFcn = createCallbackFcn(app, @StaticSliderValueChanged, true);
            app.WaterDepthmSlider.Value = 3050;
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Valve Forces', 'Roller Screw Torque', 'Motor Torque', 'Mechanical Energy'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @StaticSliderValueChanged, true);
            app.DropDown.Value = 'Valve Forces';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartResetButton = uibutton(app.UIFigure, 'push');
            app.StartResetButton.ButtonPushedFcn = createCallbackFcn(app, @StartResetButtonPushed, true);
            app.StartResetButton.Text = 'Start/Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end